classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SkyBoldButton       matlab.ui.control.Button
        DockBoldButton      matlab.ui.control.Button
        SkyThinButton       matlab.ui.control.Button
        Image               matlab.ui.control.UIAxes
        Mask                matlab.ui.control.UIAxes
        ImagesLabel         matlab.ui.control.Label
        DockThinButton      matlab.ui.control.Button
        TextColorLabel      matlab.ui.control.Label
        RSpinnerLabel       matlab.ui.control.Label
        RSpinner            matlab.ui.control.Spinner
        GSpinnerLabel       matlab.ui.control.Label
        GSpinner            matlab.ui.control.Spinner
        BSpinnerLabel       matlab.ui.control.Label
        BSpinner            matlab.ui.control.Spinner
        GenerateMaskButton  matlab.ui.control.Button
        INPAINTButton       matlab.ui.control.Button
        CityBoldButton      matlab.ui.control.Button
        CityThinButton      matlab.ui.control.Button
    end

% global montreal;
%             global r;
%             global g;
%             global b;
%             global mask;
%             
%             dimens = size(r);
%             
%             disp(dimens(1));
%             disp(dimens(2));
%             
%             for y = 1:dimens(2)
%                 for x = 1:dimens(1)
%                     if mask(x, y) == 1
%                         r(x, y) = r(x-1, y-1);
%                         g(x, y) = g(x-1, y-1);
%                         b(x, y) = b(x-1, y-1);
%                     end
%                 end
%             end
%             
%       
%             
%             montreal(:,:,1) = uint8(r);
%             montreal(:,:,2) = uint8(g);
%             montreal(:,:,3) = uint8(b);
%             
%             imshow(montreal, 'Parent', app.Image);
%             imwrite(montreal, 'Output.jpg');

    methods (Access = private)

        % Button pushed function: SkyBoldButton
        function skyBold(app, event)
            global montreal;
            global r;
            global g;
            global b;
            global mask;
            
            mask = zeros(3480, 4640, 3, 'uint8');
            montreal = imread('Sky Bold.jpg');
            
            r = montreal(:, :, 1);
            g = montreal(:, :, 2);
            b = montreal(:, :, 3);
            
            imshow(montreal, 'Parent', app.Image);
            imshow(mask, 'Parent', app.Mask);
        end

        % Button pushed function: DockBoldButton
        function dockBold(app, event)
            global montreal;
            global r;
            global g;
            global b;
            global mask;
            
            mask = zeros(3480, 4640, 3, 'uint8');
            montreal = imread('Dock Bold.jpg');
            
            r = montreal(:, :, 1);
            g = montreal(:, :, 2);
            b = montreal(:, :, 3);
            
            imshow(montreal, 'Parent', app.Image);
            imshow(mask, 'Parent', app.Mask);
        end

        % Button pushed function: SkyThinButton
        function skyThin(app, event)
            global montreal;
            global r;
            global g;
            global b;
            global mask;
            
            mask = zeros(3480, 4640, 3, 'uint8');
            montreal = imread('Sky Thin.jpg');
            
            r = montreal(:, :, 1);
            g = montreal(:, :, 2);
            b = montreal(:, :, 3);
            
            imshow(montreal, 'Parent', app.Image);
            imshow(mask, 'Parent', app.Mask);
        end

        % Button pushed function: DockThinButton
        function dockThin(app, event)
            global montreal;
            global r;
            global g;
            global b;
            global mask;
            
            mask = zeros(3480, 4640, 3, 'uint8');
            montreal = imread('Dock Thin.jpg');
            
            r = montreal(:, :, 1);
            g = montreal(:, :, 2);
            b = montreal(:, :, 3);
            
            imshow(montreal, 'Parent', app.Image);
            imshow(mask, 'Parent', app.Mask);
        end

        % Button pushed function: GenerateMaskButton
        function generateMask(app, event)
            global montreal;
            global r;
            global g;
            global b;
            global mask;
            
            textR = app.RSpinner.Value;
            textG = app.GSpinner.Value;
            textB = app.BSpinner.Value;
            
            mask = (r <= textR + 3 & r >= textR - 3) & (g <= textG + 3 & g >= textG - 3) & (b <= textB + 3 & b >= textB - 3);
            mask = imdilate(mask, [1 1 1; 1 1 1; 1 1 1]);
            
            imwrite(mask, 'mask.jpg');
            imshow(mask, 'Parent', app.Mask);
        end

        % Button pushed function: INPAINTButton
        function inpaint(app, event)
            global montreal;
            global r;
            global g;
            global b;
            global mask;
            
            dimens = size(r);
            
            disp(dimens(1));
            disp(dimens(2));
            
            r1 = r;
            g1 = g;
            b1 = b;
            
            for y = 1:dimens(2)
                for x = 1:dimens(1)
                    if mask(x, y) == 1
                        r1(x, y) = r1(x, y-1);
                        g1(x, y) = g1(x, y-1);
                        b1(x, y) = b1(x, y-1);
                    end
                end
            end
            
            i1(:,:,1) = r1;
            i1(:,:,2) = g1;
            i1(:,:,3) = b1;
            
            imshow(i1, 'Parent', app.Image);
            
            r2 = r;
            g2 = g;
            b2 = b;
            
            for y = 1:dimens(2)
                for x = 1:dimens(1)
                    if mask(x, y) == 1
                        r2(x, y) = r2(x-1, y-1);
                        g2(x, y) = g2(x-1, y-1);
                        b2(x, y) = b2(x-1, y-1);
                    end
                end
            end
            
            i2(:,:,1) = r2;
            i2(:,:,2) = g2;
            i2(:,:,3) = b2;
            
            r3 = r;
            g3 = g;
            b3 = b;
            
            for y = 1:dimens(2)
                for x = 1:dimens(1)
                    if mask(x, y) == 1
                        r3(x, y) = r3(x-1, y);
                        g3(x, y) = g3(x-1, y);
                        b3(x, y) = b3(x-1, y);
                    end
                end
            end
            
            i3(:,:,1) = r3;
            i3(:,:,2) = g3;
            i3(:,:,3) = b3;
            
            montreal = uint8((double(i1) + double(i2) + double(i3)) / 3);
            
            imshow(montreal, 'Parent', app.Image);
            imwrite(montreal, 'Output.jpg');
        end

        % Button pushed function: CityBoldButton
        function cityBold(app, event)
            global montreal;
            global r;
            global g;
            global b;
            global mask;
            
            mask = zeros(3480, 4640, 3, 'uint8');
            montreal = imread('City Bold.jpg');
            
            r = montreal(:, :, 1);
            g = montreal(:, :, 2);
            b = montreal(:, :, 3);
            
            imshow(montreal, 'Parent', app.Image);
            imshow(mask, 'Parent', app.Mask);
        end

        % Button pushed function: CityThinButton
        function cityThin(app, event)
            global montreal;
            global r;
            global g;
            global b;
            global mask;
            
            mask = zeros(3480, 4640, 3, 'uint8');
            montreal = imread('City Thin.jpg');
            
            r = montreal(:, :, 1);
            g = montreal(:, :, 2);
            b = montreal(:, :, 3);
            
            imshow(montreal, 'Parent', app.Image);
            imshow(mask, 'Parent', app.Mask);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SkyBoldButton = uibutton(app.UIFigure, 'push');
            app.SkyBoldButton.ButtonPushedFcn = createCallbackFcn(app, @skyBold, true);
            app.SkyBoldButton.Text = 'Sky Bold';
            app.DockBoldButton = uibutton(app.UIFigure, 'push');
            app.DockBoldButton.ButtonPushedFcn = createCallbackFcn(app, @dockBold, true);
            app.DockBoldButton.Text = 'Dock Bold';
            app.SkyThinButton = uibutton(app.UIFigure, 'push');
            app.SkyThinButton.ButtonPushedFcn = createCallbackFcn(app, @skyThin, true);
            app.SkyThinButton.Text = 'Sky Thin';
            app.Image = uiaxes(app.UIFigure);
            title(app.Image, 'Image')
            app.Mask = uiaxes(app.UIFigure);
            title(app.Mask, 'Mask')
            app.ImagesLabel = uilabel(app.UIFigure);
            app.ImagesLabel.Text = 'Images';
            app.DockThinButton = uibutton(app.UIFigure, 'push');
            app.DockThinButton.ButtonPushedFcn = createCallbackFcn(app, @dockThin, true);
            app.DockThinButton.Text = 'Dock Thin';
            app.TextColorLabel = uilabel(app.UIFigure);
            app.TextColorLabel.Text = 'Text Color';
            app.RSpinnerLabel = uilabel(app.UIFigure);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner = uispinner(app.UIFigure);
            app.RSpinner.Limits = [0 255];
            app.RSpinner.ValueDisplayFormat = '%.0f';
            app.GSpinnerLabel = uilabel(app.UIFigure);
            app.GSpinnerLabel.Text = 'G';
            app.GSpinner = uispinner(app.UIFigure);
            app.GSpinner.Limits = [0 255];
            app.BSpinnerLabel = uilabel(app.UIFigure);
            app.BSpinnerLabel.Text = 'B';
            app.BSpinner = uispinner(app.UIFigure);
            app.BSpinner.Limits = [0 255];
            app.GenerateMaskButton = uibutton(app.UIFigure, 'push');
            app.GenerateMaskButton.ButtonPushedFcn = createCallbackFcn(app, @generateMask, true);
            app.GenerateMaskButton.Text = 'Generate Mask';
            app.INPAINTButton = uibutton(app.UIFigure, 'push');
            app.INPAINTButton.ButtonPushedFcn = createCallbackFcn(app, @inpaint, true);
            app.INPAINTButton.Text = 'INPAINT';
            app.CityBoldButton = uibutton(app.UIFigure, 'push');
            app.CityBoldButton.ButtonPushedFcn = createCallbackFcn(app, @cityBold, true);
            app.CityBoldButton.Text = 'City Bold';
            app.CityThinButton = uibutton(app.UIFigure, 'push');
            app.CityThinButton.ButtonPushedFcn = createCallbackFcn(app, @cityThin, true);
            app.CityThinButton.Text = 'City Thin';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end